classdef Matlab_cheap_Calc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ResultEditField            matlab.ui.control.NumericEditField
        ResultEditFieldLabel       matlab.ui.control.Label
        FirstValueEditField        matlab.ui.control.NumericEditField
        FirstValueEditFieldLabel   matlab.ui.control.Label
        SecondValueEditField       matlab.ui.control.NumericEditField
        SecondValueEditFieldLabel  matlab.ui.control.Label
        VeryGoodCalculatorLabel    matlab.ui.control.Label
        DivideButton               matlab.ui.control.Button
        MultiplyButton             matlab.ui.control.Button
        SubtractButton             matlab.ui.control.Button
        AddButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            a=app.FirstValueEditField.Value;
            b=app.SecondValueEditField.Value;
            c=a+b;
            app.ResultEditField.Value=c;
        end

        % Button pushed function: SubtractButton
        function SubtractButtonPushed(app, event)
            a=app.FirstValueEditField.Value;
            b=app.SecondValueEditField.Value;
            c=a-b;
            app.ResultEditField.Value=c;
        end

        % Button pushed function: MultiplyButton
        function MultiplyButtonPushed(app, event)
            a=app.FirstValueEditField.Value;
            b=app.SecondValueEditField.Value;
            c=a*b;
            app.ResultEditField.Value=c;
        end

        % Button pushed function: DivideButton
        function DivideButtonPushed(app, event)
            a=app.FirstValueEditField.Value;
            b=app.SecondValueEditField.Value;
            c=a/b;
            app.ResultEditField.Value=c;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.SubtractButton = uibutton(app.UIFigure, 'push');
            app.SubtractButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractButtonPushed, true);
            app.SubtractButton.Text = 'Subtract';
            app.MultiplyButton = uibutton(app.UIFigure, 'push');
            app.MultiplyButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyButtonPushed, true);
            app.MultiplyButton.Text = 'Multiply';
            app.DivideButton = uibutton(app.UIFigure, 'push');
            app.DivideButton.ButtonPushedFcn = createCallbackFcn(app, @DivideButtonPushed, true);
            app.DivideButton.Text = 'Divide';
            app.VeryGoodCalculatorLabel = uilabel(app.UIFigure);
            app.VeryGoodCalculatorLabel.Text = 'Very Good Calculator';
            app.SecondValueEditFieldLabel = uilabel(app.UIFigure);
            app.SecondValueEditFieldLabel.Text = 'Second Value';
            app.SecondValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.FirstValueEditFieldLabel = uilabel(app.UIFigure);
            app.FirstValueEditFieldLabel.Text = 'First Value';
            app.FirstValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Matlab_cheap_Calc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end